classdef DHparameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DHTable                        matlab.ui.control.Table
        AddjointButton                 matlab.ui.control.Button
        TypeinDHparametersEg2010300TextAreaLabel  matlab.ui.control.Label
        TypeinDHparametersEg2010300TextArea  matlab.ui.control.TextArea
        DeletelastjointButton          matlab.ui.control.Button
        ShowmanipulatordynamicsButton_2  matlab.ui.control.Button
        DHparametersLabel              matlab.ui.control.Label
        ShowmotionequationButton       matlab.ui.control.StateButton
        Label                          matlab.ui.control.Label
        ShowPDcontrol2linksonlyButton  matlab.ui.control.StateButton
        Showimpedancecontrol2linksonlyButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        dynamicGUI; % GUI for dynamics
        meqGUI; % GUI for motion equation
        PDGUI; % GUI for PD control
        impedanceGUI; % GUI for impedance control
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddjointButton
        function AddjointButtonPushed(app, event)
            inputstr = app.TypeinDHparametersEg2010300TextArea.Value{1,1}
            
            vals = strsplit(inputstr,' ')
            [rows, cols] = size(vals)
            if cols ~= 4
                warndlg('Please input 4 numbers representing DH parameters','Warning');
            else
                for i=1:4
                    vals{1,i} = str2double(vals{1,i});
                end
                currentData = app.DHTable.Data;
                app.DHTable.Data = [currentData; vals];
            end
        end

        % Button pushed function: DeletelastjointButton
        function DeletelastjointButtonPushed(app, event)
            tmp = app.DHTable.Data
            [len, useless] = size(app.DHTable.Data)
            app.DHTable.Data(len,:) = [];
        end

        % Button pushed function: ShowmanipulatordynamicsButton_2
        function ShowmanipulatordynamicsButton_2Pushed(app, event)
            [rows,nothing] = size(app.DHTable.Data)
            if rows>5
                warndlg('We only support dynamic calculation with less than 6 arms.','Warning');
            else
                app.dynamicGUI = dynamic_GUI(app.DHTable.Data);
            end
        end

        % Value changed function: ShowmotionequationButton
        function ShowmotionequationButtonValueChanged(app, event)

            [rows,nothing] = size(app.DHTable.Data)
            if rows>5
                warndlg('We only support motion euqation with less than 6 arms.','Warning');
            else
                app.meqGUI = meq_GUI(app.DHTable.Data);
            end
        end

        % Value changed function: ShowPDcontrol2linksonlyButton
        function ShowPDcontrol2linksonlyButtonValueChanged(app, event)
            [rows,nothing] = size(app.DHTable.Data)
            if rows~=2
                warndlg('We only support PD control with 2 arms.','Warning');
            else
                app.PDGUI = PDcontrol_GUI(app.DHTable.Data);
            end
        end

        % Button pushed function: 
        % Showimpedancecontrol2linksonlyButton
        function Showimpedancecontrol2linksonlyButtonPushed(app, event)
            [rows,nothing] = size(app.DHTable.Data)
            if rows~=2
                warndlg('We only support PD control with 2 arms.','Warning');
            else
                app.impedanceGUI = impedance_GUI(app.DHTable.Data);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'alpha'; 'a'; 'theta'; 'd'};
            app.DHTable.RowName = {};
            app.AddjointButton = uibutton(app.UIFigure, 'push');
            app.AddjointButton.ButtonPushedFcn = createCallbackFcn(app, @AddjointButtonPushed, true);
            app.AddjointButton.Text = 'Add joint';
            app.TypeinDHparametersEg2010300TextAreaLabel = uilabel(app.UIFigure);
            app.TypeinDHparametersEg2010300TextAreaLabel.Text = {'Type in DH parameters'; 'E.g. 20 10 30 0'; ''};
            app.TypeinDHparametersEg2010300TextArea = uitextarea(app.UIFigure);
            app.TypeinDHparametersEg2010300TextArea.Value = {'0 1 0 0'};
            app.DeletelastjointButton = uibutton(app.UIFigure, 'push');
            app.DeletelastjointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastjointButtonPushed, true);
            app.DeletelastjointButton.Text = 'Delete last joint';
            app.ShowmanipulatordynamicsButton_2 = uibutton(app.UIFigure, 'push');
            app.ShowmanipulatordynamicsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowmanipulatordynamicsButton_2Pushed, true);
            app.ShowmanipulatordynamicsButton_2.Text = 'Show manipulator dynamics ';
            app.DHparametersLabel = uilabel(app.UIFigure);
            app.DHparametersLabel.Text = 'DH parameters';
            app.ShowmotionequationButton = uibutton(app.UIFigure, 'state');
            app.ShowmotionequationButton.ValueChangedFcn = createCallbackFcn(app, @ShowmotionequationButtonValueChanged, true);
            app.ShowmotionequationButton.Text = 'Show motion equation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-----------------------------------------------------------------------------------------------------------------------';
            app.ShowPDcontrol2linksonlyButton = uibutton(app.UIFigure, 'state');
            app.ShowPDcontrol2linksonlyButton.ValueChangedFcn = createCallbackFcn(app, @ShowPDcontrol2linksonlyButtonValueChanged, true);
            app.ShowPDcontrol2linksonlyButton.Text = 'Show PD control (2 links only)';
            app.Showimpedancecontrol2linksonlyButton = uibutton(app.UIFigure, 'push');
            app.Showimpedancecontrol2linksonlyButton.ButtonPushedFcn = createCallbackFcn(app, @Showimpedancecontrol2linksonlyButtonPushed, true);
            app.Showimpedancecontrol2linksonlyButton.Text = 'Show impedance control (2 links only)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DHparameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end