classdef PDcontrol_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PD_Table                  matlab.ui.control.Table
        DHparametersLabel         matlab.ui.control.Label
        PDControlLabel            matlab.ui.control.Label
        TypeinextrainformationneededforPDcontrolcalculationLabel  matlab.ui.control.Label
        PD_Data                   matlab.ui.control.TextArea
        AdddataButton             matlab.ui.control.Button
        DeletedataButton          matlab.ui.control.Button
        CalculatePDcontrolButton  matlab.ui.control.StateButton
        Label                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs % DH parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = cell2mat(dh_parameters);
        end

        % Button pushed function: AdddataButton
        function AdddataButtonPushed(app, event)
            inputstr = app.PD_Data.Value{1,1}
            vals = strsplit(inputstr,' ')
            [rows, cols] = size(vals);
            if cols ~= 18
                warndlg('Please input extactly 18 parameters.','Warning');
            else
                for i=1:18
                    vals{1,i} = str2double(vals{1,i});
                end
                app.PD_Table.Data = vals;
            end
        end

        % Button pushed function: DeletedataButton
        function DeletedataButtonPushed(app, event)
            tmp = app.PD_Table.Data
            [len, useless] = size(app.PD_Table.Data)
            app.PD_Table.Data(len,:) = [];
        end

        % Value changed function: CalculatePDcontrolButton
        function CalculatePDcontrolButtonValueChanged(app, event)
            PD_data = cell2mat(app.PD_Table.Data);
            xd1 = PD_data(1);
            xd2 = PD_data(2);
            K_D = PD_data(3);
            K_P = PD_data(4);
            a1 = app.dhs(1,2);
            a2 = app.dhs(2,2);
            friction = PD_data(5);
            IL1 = PD_data(6);
            IL2 = PD_data(7);
            mL1 = PD_data(8);
            mL2 = PD_data(9);
            mm2 = PD_data(10);
            L1 = PD_data(11);
            L2 = PD_data(12);
            kr1 = PD_data(13);
            kr2 = PD_data(14);
            Im1 = PD_data(15);
            Im2 = PD_data(16);
            q1 = PD_data(17);
            q2 = PD_data(18);
            PD_entrance(xd1, xd2, K_D, K_P, a1, a2, friction, IL1, IL2, mL1, mL2, mm2, L1, L2, kr1, kr2, Im1, Im2, q1, q2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PD_Table = uitable(app.UIFigure);
            app.PD_Table.ColumnName = {'xd1'; 'xd2'; 'KD'; 'KP'; 'friction'; 'IL1'; 'IL2'; 'mL1'; 'mL2'; 'mm2'; 'L1'; 'L2'; 'kr1'; 'kr2'; 'Im1'; 'Im2'; 'q1'; 'q2'};
            app.PD_Table.RowName = {};
            app.DHparametersLabel = uilabel(app.UIFigure);
            app.DHparametersLabel.Text = 'DH parameters';
            app.PDControlLabel = uilabel(app.UIFigure);
            app.PDControlLabel.Text = 'PD Control';
            app.TypeinextrainformationneededforPDcontrolcalculationLabel = uilabel(app.UIFigure);
            app.TypeinextrainformationneededforPDcontrolcalculationLabel.Text = {'Type in extra information needed '; 'for PD control calculation as the '; 'sequence shown on table. '};
            app.PD_Data = uitextarea(app.UIFigure);
            app.PD_Data.Value = {'1 0 10 10 1 1 1 1 1 1 1 1 1 1 1 1 0.5 0.5'};
            app.AdddataButton = uibutton(app.UIFigure, 'push');
            app.AdddataButton.ButtonPushedFcn = createCallbackFcn(app, @AdddataButtonPushed, true);
            app.AdddataButton.Text = 'Add data';
            app.DeletedataButton = uibutton(app.UIFigure, 'push');
            app.DeletedataButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedataButtonPushed, true);
            app.DeletedataButton.Text = 'Delete data';
            app.CalculatePDcontrolButton = uibutton(app.UIFigure, 'state');
            app.CalculatePDcontrolButton.ValueChangedFcn = createCallbackFcn(app, @CalculatePDcontrolButtonValueChanged, true);
            app.CalculatePDcontrolButton.Text = 'Calculate PD control';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'xd represents the desired position, velocity and acceleration. '; 'K_D is the damping matrix and K_P is the stiffness matrix. '; 'a1 and a2 are from the DH parameters. '; 'IL1 and IL2 are the inertia of links. Im1 and Im2 are the inertia of motors. '; 'mL1, mL2 and mm2 are the mass. '; 'L1 and L2 represent the length of links. '; 'kr1 and kr2 are the gear ratios. '; 'qi is the intial q.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDcontrol_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end