classdef dynamic_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        dynamics_Table             matlab.ui.control.Table
        TypeinextrainformationneededfordynamiccalculationLabel  matlab.ui.control.Label
        dynamics_Data              matlab.ui.control.TextArea
        GetdynamicsofsystemButton  matlab.ui.control.Button
        ManipulatorDynamicsLabel   matlab.ui.control.Label
        DeletelastinputButton      matlab.ui.control.Button
        InsertdataButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        dhs;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = dh_parameters;
        end

        % Button pushed function: GetdynamicsofsystemButton
        function GetdynamicsofsystemButtonPushed(app, event)
            dh = cell2mat(app.dhs)
            lengths = cell2mat(app.dynamics_Table.Data(:,1))'
            masses = cell2mat(app.dynamics_Table.Data(:,2))'
            ratios = cell2mat(app.dynamics_Table.Data(:,3))'
            inertia_links = cell2mat(app.dynamics_Table.Data(:,4))'
            inertia_motor =  cell2mat(app.dynamics_Table.Data(:,5))'
            q0 = cell2mat(app.dynamics_Table.Data(:,6))'
            qd0 = cell2mat(app.dynamics_Table.Data(:,7))'
            dynamic_of_system(dh, lengths, masses, ratios, inertia_links, inertia_motor, q0, qd0);
        end

        % Button pushed function: InsertdataButton
        function InsertdataButtonPushed(app, event)
            inputstr = app.dynamics_Data.Value{1,1}
            vals = strsplit(inputstr,' ')
            [rows cols] = size(vals);
            if cols ~= 7
                warndlg('Please input exactly 7 parameters', 'Warning');
            else
                for i=1:7
                    vals{1,i} = str2double(vals{1,i});
                end
                currentData = app.dynamics_Table.Data;
                app.dynamics_Table.Data = [currentData; vals];
            end
        end

        % Button pushed function: DeletelastinputButton
        function DeletelastinputButtonPushed(app, event)
            tmp = app.dynamics_Table.Data
            [len, useless] = size(app.dynamics_Table.Data)
            app.dynamics_Table.Data(len,:) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.dynamics_Table = uitable(app.UIFigure);
            app.dynamics_Table.ColumnName = {'Length of links(m)'; 'Mass of links(kg)'; 'Ratios'; 'Inertia of links(kg*m^2)'; 'Inertia of motor(kg*m^2)'; 'q0(m)'; 'qd0(m/s)'};
            app.dynamics_Table.RowName = {};
            app.TypeinextrainformationneededfordynamiccalculationLabel = uilabel(app.UIFigure);
            app.TypeinextrainformationneededfordynamiccalculationLabel.Text = {'Type in extra information needed '; 'for dynamic calculation.'; 'Split your input data with spacebar.'; ''};
            app.dynamics_Data = uitextarea(app.UIFigure);
            app.dynamics_Data.Value = {'0.5 50 110 10 0.02 -50 0'};
            app.GetdynamicsofsystemButton = uibutton(app.UIFigure, 'push');
            app.GetdynamicsofsystemButton.ButtonPushedFcn = createCallbackFcn(app, @GetdynamicsofsystemButtonPushed, true);
            app.GetdynamicsofsystemButton.Text = 'Get dynamics of system';
            app.ManipulatorDynamicsLabel = uilabel(app.UIFigure);
            app.ManipulatorDynamicsLabel.Text = 'Manipulator Dynamics';
            app.DeletelastinputButton = uibutton(app.UIFigure, 'push');
            app.DeletelastinputButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastinputButtonPushed, true);
            app.DeletelastinputButton.Text = 'Delete last input';
            app.InsertdataButton = uibutton(app.UIFigure, 'push');
            app.InsertdataButton.ButtonPushedFcn = createCallbackFcn(app, @InsertdataButtonPushed, true);
            app.InsertdataButton.Text = 'Insert data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynamic_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end