classdef impedance_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImpedanceControlLabel      matlab.ui.control.Label
        impedance_Table            matlab.ui.control.Table
        Label                      matlab.ui.control.Label
        TypeinextrainformationneededforPDcontrolcalculationLabel  matlab.ui.control.Label
        impedance_Data             matlab.ui.control.TextArea
        InsertDataButton           matlab.ui.control.StateButton
        RemoveDataButton           matlab.ui.control.StateButton
        GetimpedancecontrolButton  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dhs; % DH parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = cell2mat(dh_parameters);
        end

        % Value changed function: InsertDataButton
        function InsertDataButtonValueChanged(app, event)

            inputstr = app.impedance_Data.Value{1,1}
            vals = strsplit(inputstr,' ')
            [nothing,sz] = size(vals)
            if sz ~= 27
                warndlg('Please input exactly 27 parameters','Warning');
            else
                for i=1:sz
                    vals{1,i} = str2double(vals{1,i});
                end
                app.impedance_Table.Data = vals;     
            end
        end

        % Value changed function: RemoveDataButton
        function RemoveDataButtonValueChanged(app, event)
            tmp = app.impedance_Table.Data
            [len, useless] = size(app.impedance_Table.Data)
            app.impedance_Table.Data(len,:) = [];
        end

        % Value changed function: GetimpedancecontrolButton
        function GetimpedancecontrolButtonValueChanged(app, event)

            impedance_data = cell2mat(app.impedance_Table.Data)
            a1 = app.dhs(1,2);
            a2 = app.dhs(2,2);
            xd1 = impedance_data(1);
            xd2 = impedance_data(2);
            K_D = impedance_data(3);
            K_P = impedance_data(4);
            friction = impedance_data(5);
            IL1 = impedance_data(6);
            IL2 = impedance_data(7);
            mL1 = impedance_data(8);
            mL2 = impedance_data(9);
            mm2 = impedance_data(10);
            L1 = impedance_data(11);
            L2 = impedance_data(12);
            kr1 = impedance_data(13);
            kr2 = impedance_data(14);
            Im1 = impedance_data(15);
            Im2 = impedance_data(16);
            q1 = impedance_data(17);
            q2 = impedance_data(18);
            xdd = [impedance_data(19) impedance_data(20)];
            xddd = [impedance_data(21) impedance_data(22)];
            xr = [impedance_data(23) impedance_data(24)];
            stiffness = impedance_data(25);
            mdx = impedance_data(26);
            mdy = impedance_data(27);
            impedance_entrance(xd1, xd2, K_D, K_P, a1, a2, friction, IL1, IL2, mL1, mL2, mm2, L1, L2, kr1, kr2, Im1, Im2, q1, q2,xdd, xddd, xr, stiffness, mdx, mdy)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImpedanceControlLabel = uilabel(app.UIFigure);
            app.ImpedanceControlLabel.Text = 'Impedance Control';
            app.impedance_Table = uitable(app.UIFigure);
            app.impedance_Table.ColumnName = {'xd1'; 'xd2'; 'KD'; 'KP'; 'friction'; 'IL1'; 'IL2'; 'mL1'; 'mL2'; 'mm2'; 'L1'; 'L2'; 'kr1'; 'kr2'; 'Im1'; 'Im2'; 'q1'; 'q2'; 'xdd1'; 'xdd2'; 'xddd1'; 'xddd2'; 'xr1'; 'xr2'; 'stiffness'; 'mdx'; 'mdy'};
            app.impedance_Table.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'xd represents the desired position, velocity and acceleration. '; 'For xr, it is the position in the reference frame. '; 'K_D is the damping matrix and K_P is the stiffness matrix. '; 'mdx and mdy are the composite of mass matrix.'; 'a1 and a2 are from the DH parameters. '; 'IL1 and IL2 are the inertia of links. Im1 and Im2 are the inertia of motors. '; 'mL1, mL2 and mm2 are the mass. '; 'L1 and L2 represent the length of links. '; 'kr1 and kr2 are the gear ratios. '; 'qi is the intial q.'};
            app.TypeinextrainformationneededforPDcontrolcalculationLabel = uilabel(app.UIFigure);
            app.TypeinextrainformationneededforPDcontrolcalculationLabel.Text = {'Type in extra information needed '; 'for PD control calculation as the '; 'sequence shown on table. '};
            app.impedance_Data = uitextarea(app.UIFigure);
            app.impedance_Data.Value = {'1 0 10 10 1 1 1 1 1 1 1 1 1 1 1 1 0.5 0.5 1 0 1 0 1 0 100 1 1'};
            app.InsertDataButton = uibutton(app.UIFigure, 'state');
            app.InsertDataButton.ValueChangedFcn = createCallbackFcn(app, @InsertDataButtonValueChanged, true);
            app.InsertDataButton.Text = 'Insert Data';
            app.RemoveDataButton = uibutton(app.UIFigure, 'state');
            app.RemoveDataButton.ValueChangedFcn = createCallbackFcn(app, @RemoveDataButtonValueChanged, true);
            app.RemoveDataButton.Text = 'Remove Data';
            app.GetimpedancecontrolButton = uibutton(app.UIFigure, 'state');
            app.GetimpedancecontrolButton.ValueChangedFcn = createCallbackFcn(app, @GetimpedancecontrolButtonValueChanged, true);
            app.GetimpedancecontrolButton.Text = 'Get impedance control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = impedance_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end