classdef meq_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MotionEquationLabel      matlab.ui.control.Label
        meq_Table                matlab.ui.control.Table
        TypeinextrainformationneededfordynamiccalculationLabel  matlab.ui.control.Label
        meq_Data                 matlab.ui.control.TextArea
        InsertdataButton         matlab.ui.control.Button
        DeletelastinputButton    matlab.ui.control.Button
        GetmotionequationButton  matlab.ui.control.StateButton
        meq_answer               matlab.ui.control.Label
    end

    
    properties (Access = private)
        dhs; % DH parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dh_parameters)
            app.dhs = dh_parameters
        end

        % Button pushed function: InsertdataButton
        function InsertdataButtonPushed(app, event)
            inputstr = app.meq_Data.Value{1,1}
            vals = strsplit(inputstr,' ')
            [nothing, sz] = size(vals);
            if sz~=7
                warndlg('Please input exactly 7 parameters','Warning');
            else
                for i=1:7
                    vals{1,i} = str2double(vals{1,i});
                end
                currentData = app.meq_Table.Data;
                app.meq_Table.Data = [currentData; vals];
            end
        end

        % Button pushed function: DeletelastinputButton
        function DeletelastinputButtonPushed(app, event)
            tmp = app.meq_Table.Data
            [len, useless] = size(app.meq_Table.Data)
            app.meq_Table.Data(len,:) = [];
        end

        % Value changed function: GetmotionequationButton
        function GetmotionequationButtonValueChanged(app, event)
            [rows_dhs cols] = size(cell2mat(app.dhs));
            [rows_meq cols1] = size(cell2mat(app.meq_Table.Data));
            if rows_dhs ~= rows_meq
                warndlg('Number of links do not pair.','Warning');
            else
                re = motion_equation(cell2mat(app.dhs), app.meq_Table.Data)
                strtmp ="";
                [nothing,num_tau] = size(re);
                for i=1:num_tau
                    strtmp = strtmp+newline+sprintf("tau%d=%s",i,re(i));
                end
                app.meq_answer.Text = join(strtmp,'---');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MotionEquationLabel = uilabel(app.UIFigure);
            app.MotionEquationLabel.Text = 'Motion Equation';
            app.meq_Table = uitable(app.UIFigure);
            app.meq_Table.ColumnName = {'kr'; 'ml'; 'mm'; 'IL'; 'Im'; 'tau'; 'type'};
            app.meq_Table.RowName = {};
            app.TypeinextrainformationneededfordynamiccalculationLabel = uilabel(app.UIFigure);
            app.TypeinextrainformationneededfordynamiccalculationLabel.Text = {'Type in extra information needed '; 'for motion equation calculation.'; 'Split your input data with spacebar.'; 'For type, 1 stands for prismatic joint,'; 'while 2 stands for revolute joint.'; ''};
            app.meq_Data = uitextarea(app.UIFigure);
            app.meq_Data.Value = {'0.5 50 110 10 0.02 -50 0'};
            app.InsertdataButton = uibutton(app.UIFigure, 'push');
            app.InsertdataButton.ButtonPushedFcn = createCallbackFcn(app, @InsertdataButtonPushed, true);
            app.InsertdataButton.Text = 'Insert data';
            app.DeletelastinputButton = uibutton(app.UIFigure, 'push');
            app.DeletelastinputButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastinputButtonPushed, true);
            app.DeletelastinputButton.Text = 'Delete last input';
            app.GetmotionequationButton = uibutton(app.UIFigure, 'state');
            app.GetmotionequationButton.ValueChangedFcn = createCallbackFcn(app, @GetmotionequationButtonValueChanged, true);
            app.GetmotionequationButton.Text = 'Get motion equation';
            app.meq_answer = uilabel(app.UIFigure);
            app.meq_answer.Text = 'Answer will be displayed here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meq_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end